classdef TS_StressFiberSegApp_StudentVersion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SaveLamp                  matlab.ui.control.Lamp
        SaveLampLabel             matlab.ui.control.Label
        DrawingModeLamp           matlab.ui.control.Lamp
        DrawingModeLampLabel      matlab.ui.control.Label
        ClearFigureButton         matlab.ui.control.Button
        SaveSegmentationButton    matlab.ui.control.Button
        UnDoSegmentingButton      matlab.ui.control.Button
        StartSegmentingButton     matlab.ui.control.Button
        ApplyPreprocessingButton  matlab.ui.control.Button
        TSStressFiberSegmentationAppLabel  matlab.ui.control.Label
        LoadImageButton           matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        fileName % Description
        I_Cell
        Range1=100;
        Range2=65535;
        MaskSave= [];
        Mask
        i=0 %Number of segments
        H %Drawn trace
        ax %saved axis
        Index=1;
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [app.fileName, pathName, ~] = uigetfile({'*.*',  'All Files (*.*)'});
            app.I_Cell=imread([pathName,app.fileName]);
            imagesc(app.UIAxes,mat2gray(app.I_Cell));
            colormap(app.UIAxes,"gray")
            axis(app.UIAxes,'image')
            
        end

        % Button pushed function: ApplyPreprocessingButton
        function ApplyPreprocessingButtonPushed(app, event)
            I=app.I_Cell;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  Add Preprocessing code here
            %=======================================================================================================================================
            %The Image is inputted as I            
            %Output image (after preprocessing should be defined as I_Cell)
            
            I = imclearborder(I); %ADDED THIS
            [~,threshold] = edge(I,'sobel');
            fudgeFactor = 0.4; %changed from 0.5 to 0.4 
            BWs = edge(I,'sobel',threshold * fudgeFactor);
            se90 = strel('line',5,90); %changed from 3 to 5
            se0 = strel('line',5,0);
            BWsdil = imdilate(BWs,[se90 se0]);
            BWdfill = imfill(BWsdil,'holes');
            BWnobord = imclearborder(BWdfill,4);
            seD = strel('diamond',1);
            BWfinal = imerode(BWnobord,seD);
            BWfinal = imerode(BWfinal,seD);
            %% new stuff
            BWfinal = bwareafilt(BWfinal,1);
            I(~BWfinal) = 0;
            I_Cell = adapthisteq(I);
            
            %=======================================================================================================================================


            %% For Easier Tracing
            IMData=double(I_Cell(:));
            IMData(find(I_Cell==0))=[ ];
            M=mean(IMData);
            SD=std(IMData);
            RangeCut=M+3*SD;
            app.Range1=100;%Starting Intensity We consider
            app.Range2=M+3*SD;%Maximum intensity we consider for FLattenning
            
            app.I_Cell=I_Cell;
            imagesc(app.UIAxes,mat2gray(app.I_Cell));
            colormap(app.UIAxes,"gray")
            axis(app.UIAxes,'image')
        end

        % Button pushed function: StartSegmentingButton
        function StartSegmentingButtonPushed(app, event)
            I_Cell=app.I_Cell;
            Range1=app.Range1;
            Range2=app.Range2;
            MaskSave=app.MaskSave;
            Mask=zeros(size(I_Cell));
            for j = 1:length(MaskSave)
                Mask=app.MaskSave{j}+Mask;
            end
            
            %% Segment the Cells
            IM=mat2gray(I_Cell,[Range1 Range2]);
            app.Index=1;
            app.DrawingModeLamp.Color="r";
            app.i=app.i+1;
            while app.Index==1
                imagesc(app.UIAxes,labeloverlay(IM,Mask,'Colormap','spring','Transparency',0.25))
                title(app.UIAxes,'Double click to turn off')
                colormap(app.UIAxes,'gray')
                if app.i ==1
                    axis(app.UIAxes,'image')
                else
                    axis(app.UIAxes,'image')
                    axis(app.UIAxes,app.ax);
                end
                
                H=drawpolyline(app.UIAxes);
                Mask1=createMask(H);
                se90 = strel('line',3,90);
                se0 = strel('line',3,0);
                Mask2 = imdilate(Mask1,[se90 se0]);
                if sum(sum(Mask2))<5
                    break;
                end
                
                MaskSave{app.i}=Mask2;
                Mask=Mask2+Mask;
                Mask=Mask>0;
                delete(H)
                app.ax=axis(app.UIAxes);
                app.i=app.i+1;
                app.Mask=Mask;
                app.MaskSave=MaskSave;
            end
            app.i=app.i-1;
            delete(H);
            app.DrawingModeLamp.Color="g";
        end

        % Button pushed function: UnDoSegmentingButton
        function UnDoSegmentingButtonPushed(app, event)

            IM=mat2gray(app.I_Cell,[app.Range1 app.Range2]);
            
            Mask=[ ];
            Mask=zeros(size(IM));
            for j = 1:(app.i-1)
                Mask=app.MaskSave{j}+Mask;
            end
            app.Mask=Mask>0;
            app.MaskSave(app.i)=[ ];
            app.i=app.i-1;
            imagesc(app.UIAxes,labeloverlay(IM,Mask,'Colormap','spring','Transparency',0.25))
            colormap(app.UIAxes,'gray')
            
        end

        % Callback function
        function EndSegmentationButtonPushed(app, event)
            
        end

        % Button pushed function: SaveSegmentationButton
        function SaveSegmentationButtonPushed(app, event)
            I_Cell=app.I_Cell;
            MaskSave=app.MaskSave;
            ImageNuime=app.fileName;
            
            %% Save to a format that is compatible with Instance Segmentation
            RGBImage=cat(3,I_Cell,I_Cell,I_Cell);
            bboxes=zeros(length(MaskSave),4);
            labels= [ ];
            denseMasks=false([size(I_Cell),length(MaskSave)]);
            for i =1:length(MaskSave)
                labels{i,1}='Actin';
                denseMasks(:,:,i)=MaskSave{i};
                
                Mask=MaskSave{i};
                xSum=sum(Mask,1);
                ySum=sum(Mask,2);
                bboxes(i,1)=min(find(xSum>0));
                bboxes(i,2)=min(find(ySum>0));
                bboxes(i,3)=max(find(xSum>0))-min(find(xSum>0));
                bboxes(i,4)=max(find(ySum>0))-min(find(ySum>0));
                
                %     II=insertObjectAnnotation(double(Mask),"Rectangle",bboxes(1,:),'oh');
                %     imshow(II)
            end
            
            mkdir('./DeepLearningData/')
            ReturnArray=[ ];
            ReturnArray{1,1}=RGBImage;
            ReturnArray{1,2}= bboxes;
            ReturnArray{1,3}= labels;
            ReturnArray{1,4}=  denseMasks;
            FileNuime=['./DeepLearningData/' ImageNuime(1:(end-4)) '.mat'];
            save(FileNuime,'ReturnArray')
            app.SaveLamp.Color="b";
        end

        % Button pushed function: ClearFigureButton
        function ClearFigureButtonPushed(app, event)
            app.fileName =[]; % Description
            app.I_Cell =[];
            app.Range1=100;
            app.Range2=65535;
            app.MaskSave= [];
            app.Mask =[];
            app.i =0; %Number of segments
            app.H =[]; %Drawn trace
            app.ax =[];%saved axis
            app.Index=1;
            cla(app.UIAxes)
            app.SaveLamp.Color="g";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.TSStressFiberSegmentationAppLabel = uilabel(app.UIFigure);
            app.TSStressFiberSegmentationAppLabel.Text = 'TS Stress Fiber Segmentation App';
            app.ApplyPreprocessingButton = uibutton(app.UIFigure, 'push');
            app.ApplyPreprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyPreprocessingButtonPushed, true);
            app.ApplyPreprocessingButton.Text = 'Apply Preprocessing';
            app.StartSegmentingButton = uibutton(app.UIFigure, 'push');
            app.StartSegmentingButton.ButtonPushedFcn = createCallbackFcn(app, @StartSegmentingButtonPushed, true);
            app.StartSegmentingButton.Text = 'Start Segmenting';
            app.UnDoSegmentingButton = uibutton(app.UIFigure, 'push');
            app.UnDoSegmentingButton.ButtonPushedFcn = createCallbackFcn(app, @UnDoSegmentingButtonPushed, true);
            app.UnDoSegmentingButton.Text = 'UnDo Segmenting';
            app.SaveSegmentationButton = uibutton(app.UIFigure, 'push');
            app.SaveSegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSegmentationButtonPushed, true);
            app.SaveSegmentationButton.Text = 'Save Segmentation';
            app.ClearFigureButton = uibutton(app.UIFigure, 'push');
            app.ClearFigureButton.ButtonPushedFcn = createCallbackFcn(app, @ClearFigureButtonPushed, true);
            app.ClearFigureButton.Text = 'Clear Figure';
            app.DrawingModeLampLabel = uilabel(app.UIFigure);
            app.DrawingModeLampLabel.Text = 'Drawing Mode';
            app.DrawingModeLamp = uilamp(app.UIFigure);
            app.SaveLampLabel = uilabel(app.UIFigure);
            app.SaveLampLabel.Text = 'Save';
            app.SaveLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TS_StressFiberSegApp_StudentVersion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end